classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotDistributionButton          matlab.ui.control.Button
        SelectDistributionTypeDropDown  matlab.ui.control.DropDown
        SelectDistributionTypeDropDownLabel  matlab.ui.control.Label
        wlln                            matlab.ui.control.UIAxes
        distribution                    matlab.ui.control.UIAxes
        mean_square                     matlab.ui.control.UIAxes
        almost_sure                     matlab.ui.control.UIAxes
        probability                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotDistributionButton
        function SelectDistributionTypeDropDownClicked(app, event)
        value = app.SelectDistributionTypeDropDown.Value;
        if strcmpi(value,'Exponential')
            [probab,almost_sure_con, mean_square_con, dist,T] = exponential_convergence();
            plot(app.distribution,0:0.00001:2,dist,'r','LineWidth',2);
        elseif strcmpi(value,'Normal')
            [probab,almost_sure_con, mean_square_con, dist,T] = normal_convergence();
            plot(app.distribution,0:0.00001:2,dist,'r','LineWidth',2);
        else
            [probab,almost_sure_con, mean_square_con, dist,T] = uniform_convergence();
            plot(app.distribution,0:0.00001:3,dist,'r','LineWidth',2);
        end
        plot(app.probability,1:1:2000,probab(1:1:2000),'magenta','LineWidth',2);
        plot(app.almost_sure,1:1:1000,almost_sure_con(1:1:1000),'blue','LineWidth',2);
        plot(app.mean_square,1:1:2000,mean_square_con(1:1:2000),'green','LineWidth',2);
        plot(app.wlln,1:1:2000,T(1:500,1:1:2000));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.probability = uiaxes(app.UIFigure);
            title(app.probability, 'Convergence in Probability')
            xlabel(app.probability, 'No. of Samples (n)')
            ylabel(app.probability, 'Z=Y-E[X]')
            zlabel(app.probability, 'Z')
            app.almost_sure = uiaxes(app.UIFigure);
            title(app.almost_sure, 'Almost Sure Convergence')
            xlabel(app.almost_sure, 'No. of Samples (n)')
            ylabel(app.almost_sure, 'Z=Y-E[X]')
            zlabel(app.almost_sure, 'Z')
            app.mean_square = uiaxes(app.UIFigure);
            title(app.mean_square, 'Mean Square Convergence')
            xlabel(app.mean_square, 'No. of Samples (n)')
            ylabel(app.mean_square, 'M(n)')
            zlabel(app.mean_square, 'Z')
            app.distribution = uiaxes(app.UIFigure);
            title(app.distribution, 'Convergence in Distribution')
            xlabel(app.distribution, 'No. of Samples(n)')
            ylabel(app.distribution, 'Fy')
            zlabel(app.distribution, 'Z')
            app.wlln = uiaxes(app.UIFigure);
            title(app.wlln, 'WLLN')
            xlabel(app.wlln, 'No. of Samples (n)')
            ylabel(app.wlln, {'|X(n'; 'w) - X(w)|'})
            zlabel(app.wlln, 'Z')
            app.SelectDistributionTypeDropDownLabel = uilabel(app.UIFigure);
            app.SelectDistributionTypeDropDownLabel.Text = 'Select Distribution Type';
            app.SelectDistributionTypeDropDown = uidropdown(app.UIFigure);
            app.SelectDistributionTypeDropDown.Items = {'Exponential', 'Normal', 'Uniform'};
            app.SelectDistributionTypeDropDown.Value = 'Exponential';
            app.PlotDistributionButton = uibutton(app.UIFigure, 'push');
            app.PlotDistributionButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDistributionTypeDropDownClicked, true);
            app.PlotDistributionButton.Text = 'Plot Distribution';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end